classdef runall_Pipeline2_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField                      matlab.ui.control.EditField
        GetDataPathButton              matlab.ui.control.Button
        RunAllButton                   matlab.ui.control.Button
        KnierimLabManualSortingPipeline20ForNeuropixelsProbesLabel  matlab.ui.control.Label
        RunCalcParmsButton             matlab.ui.control.Button
        RunDetectMergePeaksButton      matlab.ui.control.Button
        RunSGLXMetaToCoordsButton      matlab.ui.control.Button
        RunArtifactRemovalforPOButton  matlab.ui.control.Button
        RunTPrimeButton                matlab.ui.control.Button
        RunCatGTButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataPath = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunCatGTButton
        function RunCatGTButtonPushed(app, event)
            app.dataPath = runall_CatGT(app.dataPath);
        end

        % Button pushed function: RunTPrimeButton
        function RunTPrimeButtonPushed(app, event)
            app.dataPath = runall_TPrime(app.dataPath);
        end

        % Button pushed function: RunSGLXMetaToCoordsButton
        function RunSGLXMetaToCoordsButtonPushed(app, event)
            app.dataPath = runall_Coords(app.dataPath);
        end

        % Button pushed function: RunDetectMergePeaksButton
        function RunDetectMergePeaksButtonPushed(app, event)
            app.dataPath = runall_DetectPeaks(app.dataPath);
        end

        % Button pushed function: RunCalcParmsButton
        function RunCalcParmsButtonPushed(app, event)
            app.dataPath = runall_CalcParms(app.dataPath);
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            app.dataPath = runall_Pipeline2(app.dataPath);
        end

        % Button pushed function: GetDataPathButton
        function GetDataPathButtonPushed(app, event)
            app.dataPath = uigetdir('C:\');
            EditFieldValueChanging(app, event);
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            app.EditField.Value = sprintf('%s',app.dataPath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunCatGTButton = uibutton(app.UIFigure, 'push');
            app.RunCatGTButton.ButtonPushedFcn = createCallbackFcn(app, @RunCatGTButtonPushed, true);
            app.RunCatGTButton.Text = 'Run CatGT';
            app.RunTPrimeButton = uibutton(app.UIFigure, 'push');
            app.RunTPrimeButton.ButtonPushedFcn = createCallbackFcn(app, @RunTPrimeButtonPushed, true);
            app.RunTPrimeButton.Text = 'Run TPrime';
            app.RunArtifactRemovalforPOButton = uibutton(app.UIFigure, 'push');
            app.RunArtifactRemovalforPOButton.Text = 'Run Artifact Removal (for PO)';
            app.RunSGLXMetaToCoordsButton = uibutton(app.UIFigure, 'push');
            app.RunSGLXMetaToCoordsButton.ButtonPushedFcn = createCallbackFcn(app, @RunSGLXMetaToCoordsButtonPushed, true);
            app.RunSGLXMetaToCoordsButton.Text = 'Run SGLXMetaToCoords';
            app.RunDetectMergePeaksButton = uibutton(app.UIFigure, 'push');
            app.RunDetectMergePeaksButton.ButtonPushedFcn = createCallbackFcn(app, @RunDetectMergePeaksButtonPushed, true);
            app.RunDetectMergePeaksButton.Text = 'Run DetectMergePeaks';
            app.RunCalcParmsButton = uibutton(app.UIFigure, 'push');
            app.RunCalcParmsButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalcParmsButtonPushed, true);
            app.RunCalcParmsButton.Text = 'Run CalcParms';
            app.KnierimLabManualSortingPipeline20ForNeuropixelsProbesLabel = uilabel(app.UIFigure);
            app.KnierimLabManualSortingPipeline20ForNeuropixelsProbesLabel.Text = 'Knierim Lab Manual Sorting Pipeline 2.0 (For Neuropixels Probes)';
            app.RunAllButton = uibutton(app.UIFigure, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.GetDataPathButton = uibutton(app.UIFigure, 'push');
            app.GetDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataPathButtonPushed, true);
            app.GetDataPathButton.Text = 'Get Data Path';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = runall_Pipeline2_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end